classdef week4lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_2                  matlab.ui.container.Panel
        WarningField             matlab.ui.control.EditField
        SigmaEditFieldLabel      matlab.ui.control.Label
        SigmaEditField           matlab.ui.control.NumericEditField
        SizeEditFieldLabel       matlab.ui.control.Label
        SizeEditField            matlab.ui.control.NumericEditField
        FactorEditFieldLabel     matlab.ui.control.Label
        FactorEditField          matlab.ui.control.NumericEditField
        HighBoostButton          matlab.ui.control.Button
        HighPassButton           matlab.ui.control.Button
        LowPassButton            matlab.ui.control.Button
        HistogramEqualizeButton  matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        LoadImageButton          matlab.ui.control.Button
        UIAxes_Filtered          matlab.ui.control.UIAxes
        UIAxes_Original          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        original_image
        filtered_image
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SigmaEditField.Value = 2;
            app.SizeEditField.Value = 3;
            app.FactorEditField.Value = 2;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.WarningField.Visible = 'off';
            [fileName,pathName] = uigetfile({'*.png';'*.jpg'; '*.jpeg'},'Choose an Image (.png or .jpg');
            if not(isequal(fileName, 0))
                try
                    cla(app.UIAxes_Filtered);
                    app.UIAxes_Filtered.Title.String = '';
                    app.original_image = imread(fullfile(pathName, fileName));
                    imshow(app.original_image, 'parent', app.UIAxes_Original);
                catch
                    app.WarningField.Value = 'Could not load the selected image. Please try again.';
                    app.WarningField.Visible = 'on';
                end
            end
        end

        % Button pushed function: HistogramEqualizeButton
        function HistogramEqualizeButtonPushed(app, event)
            if isempty(app.original_image)
                app.WarningField.Value = 'No image detected. Please load an image to perform histogram equalization';
                app.WarningField.Visible = 'on';
            else
                app.filtered_image = histeq(app.original_image);
                imshow(app.filtered_image, 'parent', app.UIAxes_Filtered);
                app.UIAxes_Filtered.Title.String = 'Histogram Equalized';
            end
        end

        % Button pushed function: LowPassButton
        function LowPassButtonPushed(app, event)
            size = app.SizeEditField.Value;
            sigma = app.SigmaEditField.Value;
            if isempty(app.original_image)
                app.WarningField.Value = 'No image detected. Please load an image to apply low pass filter';
                app.WarningField.Visible = 'on'; 
            elseif (rem(size, 2) == 0)
                app.WarningField.Value = 'Size must be an odd number.';
                app.WarningField.Visible = 'on';
            elseif (sigma <= 0)
                app.WarningField.Value = 'Size must be a positive number.';
                app.WarningField.Visible = 'on';
            else
                app.WarningField.Visible = 'off';
                app.filtered_image = imgaussfilt(app.original_image, sigma, 'Filtersize', size);
                imshow(app.filtered_image, 'parent', app.UIAxes_Filtered);
                app.UIAxes_Filtered.Title.String = 'Low Pass Filtered';
            end
        end

        % Button pushed function: HighPassButton
        function HighPassButtonPushed(app, event)
            if isempty(app.original_image)
                app.WarningField.Value = 'No image detected. Please load an image to apply high pass filter';
                app.WarningField.Visible = 'on';
            else
                app.WarningField.Visible = 'off';
                img_low_pass = imgaussfilt(app.original_image, 10);
                app.filtered_image = app.original_image - img_low_pass;
                imshow(app.filtered_image, 'parent', app.UIAxes_Filtered);
                app.UIAxes_Filtered.Title.String = 'High Pass Filtered';
            end
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            if isempty(app.original_image)
                app.WarningField.Value = 'No image detected. Please load an image to apply high boost filter';
                app.WarningField.Visible = 'on';
            elseif (app.FactorEditField.Value <= 0)
                app.WarningField.Value = 'Boosting factor must be >= 1';
                app.WarningField.Visible = 'on';
            else
                app.WarningField.Visible = 'off';
                img_low_pass = imgaussfilt(app.original_image, 10);
                img_high_pass = app.original_image - img_low_pass;
                app.filtered_image = (app.FactorEditField.Value - 1) * app.original_image + img_high_pass;
                imshow(app.filtered_image, 'parent', app.UIAxes_Filtered);
                app.UIAxes_Filtered.Title.String = 'High Boost Filtered';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_Original = uiaxes(app.Panel);
            title(app.UIAxes_Original, 'Original Image')
            app.UIAxes_Original.XTick = [];
            app.UIAxes_Original.YTick = [];
            app.UIAxes_Original.Visible = 'off';
            app.UIAxes_Filtered = uiaxes(app.Panel);
            title(app.UIAxes_Filtered, 'Title')
            app.UIAxes_Filtered.XTick = [];
            app.UIAxes_Filtered.YAxisLocation = 'right';
            app.UIAxes_Filtered.YTick = [];
            app.UIAxes_Filtered.Visible = 'off';
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Panel_2 = uipanel(app.UIFigure);
            app.HistogramEqualizeButton = uibutton(app.Panel_2, 'push');
            app.HistogramEqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizeButtonPushed, true);
            app.HistogramEqualizeButton.Text = 'Histogram Equalize';
            app.LowPassButton = uibutton(app.Panel_2, 'push');
            app.LowPassButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassButtonPushed, true);
            app.LowPassButton.Text = 'Low Pass';
            app.HighPassButton = uibutton(app.Panel_2, 'push');
            app.HighPassButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassButtonPushed, true);
            app.HighPassButton.Text = 'High Pass';
            app.HighBoostButton = uibutton(app.Panel_2, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'High Boost';
            app.FactorEditField = uieditfield(app.Panel_2, 'numeric');
            app.FactorEditFieldLabel = uilabel(app.Panel_2);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.SizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SizeEditFieldLabel = uilabel(app.Panel_2);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SigmaEditField = uieditfield(app.Panel_2, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.Panel_2);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.WarningField = uieditfield(app.Panel_2, 'text');
            app.WarningField.Editable = 'off';
            app.WarningField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week4lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end