classdef FarshadGhanbari_21334883_Lab04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel_2            matlab.ui.container.Panel
        RadiusSlider       matlab.ui.control.Slider
        ApproximateRadiusforCircleDetectionSliderLabel  matlab.ui.control.Label
        ThresholdSlider    matlab.ui.control.Slider
        ThresholdforEdgeDetectionSliderLabel  matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        WarningField       matlab.ui.control.EditField
        DetectEdgesButton  matlab.ui.control.Button
        DropDown           matlab.ui.control.DropDown
        DetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        LoadImageButton    matlab.ui.control.Button
        ImageContainer     matlab.ui.container.Panel
        UIAxes_Filtered    matlab.ui.control.UIAxes
        UIAxes_Original    matlab.ui.control.UIAxes
    end

    % Global properties
    properties (Access = private)
        original_image
        gray_image
        edge_image
    end
    
    methods (Access = private)
        % Detects and draws the circles using edge_image
        % Called after sliders move or detect edge button is clicked
        function drawCircle(app, radius)
            % Setting a range of values for radius approximation
            min_radius = uint8(radius - 5);
            max_radius = uint8(radius + 5);
            % Setting the minimum value to 6 since imfindcircles is not
            % accurate for values <= 5
            if (min_radius < 6)
                min_radius = 6;
            end
            % Finding circles based on objectpolarity which looks for
            % bright circles within the radius range given.
            % Sensitivity was manually adjusted for best performance in the
            % sample images.
            [centers, radii] = imfindcircles(app.edge_image,[min_radius, max_radius], 'ObjectPolarity', 'bright', 'Sensitivity', 0.92);
            imshow(app.edge_image, 'parent', app.UIAxes_Filtered);
            imshow(app.original_image, 'parent', app.UIAxes_Original);
            % Detected circles are drawn on both filtered and original image
            viscircles(app.UIAxes_Filtered, centers, radii, 'Color', 'y');
            viscircles(app.UIAxes_Original, centers, radii, 'Color', 'y');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Clears any previous warning text
            app.WarningField.Visible = 'off';

            % Opens a dialog box in the current folder.
            % User then is able to select an image.
            [fileName,pathName] = uigetfile({'*.png; *.jpg; *.jpeg;'},'Choose an Image (.png, .jpg or .jpeg');

            % If no file was selected do nothing.
            % Otherwise store the selected image.
            if ~(isequal(fileName, 0))
                try
                    % Read and store the selected image
                    % Plot the image on the left axis
                    app.original_image = imread(fullfile(pathName, fileName));
                    imshow(app.original_image, 'parent', app.UIAxes_Original);
                    % Convert image to grayscale for edge detection
                    % Also plots the grayscale next to the original
                    app.gray_image = rgb2gray(app.original_image);
                    imshow(app.gray_image, 'parent', app.UIAxes_Filtered);
                catch
                    % Outputs an error message if there was a problem
                    app.WarningField.Value = 'Could not load the selected image. Please try again.';
                    app.WarningField.Visible = 'on';
                end
            end
        end

        % Button pushed function: DetectEdgesButton
        function DetectEdgesButtonPushed(app, event)
            % If no image has been selected yet, outputs an error message
            if isempty(app.original_image)
                app.WarningField.Value = 'No image detected. Please load an image to perform edge detection';
                app.WarningField.Visible = 'on';
            else
                % Detect edge using the given algorithm and threshold
                app.edge_image = edge(app.gray_image, app.DropDown.Value, app.ThresholdSlider.Value);
                % Calls the drawCircle function defined earlier
                app.drawCircle(app.RadiusSlider.Value);
            end
        end

        % Value changing function: RadiusSlider
        function RadiusSliderValueChanging(app, event)
            % If we don't have an image, do nothing.
            if ~isempty(app.original_image)
                % Otherwise as the value changes, update the circles.
                changingValue = event.Value;
                app.drawCircle(changingValue);
            end
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            % If we don't have an image, do nothing.
            if ~isempty(app.original_image)
                % Otherwise as the value changes, update the images and circles.
                changingValue = event.Value;
                % Detect edges with the new threshold
                app.edge_image = edge(app.gray_image, app.DropDown.Value, changingValue);
                % Dectect and draw circles on the updated image
                app.drawCircle(app.RadiusSlider.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageContainer = uipanel(app.UIFigure);
            app.ImageContainer.Title = 'Finding Circles in Images with Hough Transform';
            app.UIAxes_Original = uiaxes(app.ImageContainer);
            app.UIAxes_Original.XTick = [];
            app.UIAxes_Original.YTick = [];
            app.UIAxes_Original.Visible = 'off';
            app.UIAxes_Filtered = uiaxes(app.ImageContainer);
            app.UIAxes_Filtered.XTick = [];
            app.UIAxes_Filtered.YTick = [];
            app.UIAxes_Filtered.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DetectionAlgorithmDropDownLabel = uilabel(app.Panel);
            app.DetectionAlgorithmDropDownLabel.Text = 'Detection Algorithm';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'Canny', 'approxcanny'};
            app.DropDown.Value = 'Canny';
            app.DetectEdgesButton = uibutton(app.Panel, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Text = 'Detect Edges';
            app.WarningField = uieditfield(app.Panel, 'text');
            app.WarningField.Editable = 'off';
            app.WarningField.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ThresholdforEdgeDetectionSliderLabel = uilabel(app.Panel_2);
            app.ThresholdforEdgeDetectionSliderLabel.Text = 'Threshold for Edge Detection ';
            app.ThresholdSlider = uislider(app.Panel_2);
            app.ThresholdSlider.Limits = [0 0.99];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.48;
            app.ApproximateRadiusforCircleDetectionSliderLabel = uilabel(app.Panel_2);
            app.ApproximateRadiusforCircleDetectionSliderLabel.Text = 'Approximate Radius for Circle Detection';
            app.RadiusSlider = uislider(app.Panel_2);
            app.RadiusSlider.Limits = [6 50];
            app.RadiusSlider.ValueChangingFcn = createCallbackFcn(app, @RadiusSliderValueChanging, true);
            app.RadiusSlider.Value = 26;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FarshadGhanbari_21334883_Lab04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end